classdef SPRsim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        nmLabel_5            matlab.ui.control.Label
        BioSliderLabel_2     matlab.ui.control.Label
        BioSlider            matlab.ui.control.Slider
        BioSliderLabel       matlab.ui.control.Label
        Label                matlab.ui.control.Label
        AuSlider_2           matlab.ui.control.Slider
        nmLabel_3            matlab.ui.control.Label
        RIULabel             matlab.ui.control.Label
        nmLabel_2            matlab.ui.control.Label
        nmLabel              matlab.ui.control.Label
        ButtonGroup_2        matlab.ui.container.ButtonGroup
        AutoScaleButton      matlab.ui.control.RadioButton
        NormalizedButton     matlab.ui.control.RadioButton
        ButtonGroup          matlab.ui.container.ButtonGroup
        ExternalAngleButton  matlab.ui.control.RadioButton
        InternalAngleButton  matlab.ui.control.RadioButton
        LambdaSlider         matlab.ui.control.Slider
        LambdaSliderLabel    matlab.ui.control.Label
        WaterSlider          matlab.ui.control.Slider
        WaterSliderLabel     matlab.ui.control.Label
        TiSlider             matlab.ui.control.Slider
        TiSliderLabel        matlab.ui.control.Label
        AuSlider             matlab.ui.control.Slider
        AuSliderLabel        matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
        ContextMenu          matlab.ui.container.ContextMenu
        Menu                 matlab.ui.container.Menu
        Menu2                matlab.ui.container.Menu
    end


    properties (Access = private)
        Ti_slider = 1*1e-9; % Ti film thickness nm
        Au_slider = 50*1e-9; % Au film thickness nm
        n_water = 1.33; % water refraction index RIU
        d_bio = 2*1e-9; % biosensor layer thickness nm
        eps_bio = 2.5; % biosensor epsilon
        theta_start = 25; %initial sweep angle
        theta_stop = 55;% stop angle
        lambda = 633*1e-9; %impinging wavelenght in nm
        selected_theta = 'Internal Angle'; % selected button for SPR internal or external angle
        normalize = 'Normalized'; % set graph y limits to 0 - 1
    end

    properties (Access = public)
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            lambda = app.lambda;
            n_water = app.n_water;
            d_ti = app.Ti_slider;
            d_au = app.Au_slider;
            d_bio = app.d_bio;
            eps_bio = app.eps_bio;
            [RTM,theta_ref,theta_deg,epsilon] = SPRgui_func(lambda,d_au,d_ti,n_water,d_bio,eps_bio);

            if(strcmp(app.selected_theta,'Internal Angle'))
                x = theta_ref;
            elseif(strcmp(app.selected_theta,'External Angle'))
                x = theta_deg;
            end
            y = RTM;
            plot(app.UIAxes,x,y);
            if(strcmp(app.normalize,'Normalized'))
                ylim(app.UIAxes,[0 1]);
            else
                ylim(app.UIAxes,[-inf inf]);
            end
        end

        % Value changing function: AuSlider
        function AuSliderValueChanging(app, event)
            app.Au_slider = event.Value*1e-9;

            lambda = app.lambda;
            n_water = app.n_water;
            d_ti = app.Ti_slider;
            d_au = app.Au_slider;
            d_bio = app.d_bio;
            eps_bio = app.eps_bio;
            [RTM,theta_ref,theta_deg,epsilon] = SPRgui_func(lambda,d_au,d_ti,n_water,d_bio,eps_bio);

            if(strcmp(app.selected_theta,'Internal Angle'))
                x = theta_ref;
            elseif(strcmp(app.selected_theta,'External Angle'))
                x = theta_deg;
            end
            y = RTM;
            plot(app.UIAxes,x,y);
            if(strcmp(app.normalize,'Normalized'))
                ylim(app.UIAxes,[0 1]);
            else
                ylim(app.UIAxes,[-inf inf]);
            end
        end

        % Value changing function: TiSlider
        function TiSliderValueChanging(app, event)
            app.Ti_slider = event.Value*1e-9;

            lambda = app.lambda;
            n_water = app.n_water;
            d_ti = app.Ti_slider;
            d_au = app.Au_slider;
            d_bio = app.d_bio;
            eps_bio = app.eps_bio;
            [RTM,theta_ref,theta_deg,epsilon] = SPRgui_func(lambda,d_au,d_ti,n_water,d_bio,eps_bio);

            if(strcmp(app.selected_theta,'Internal Angle'))
                x = theta_ref;
            elseif(strcmp(app.selected_theta,'External Angle'))
                x = theta_deg;
            end
            y = RTM;
            plot(app.UIAxes,x,y);
            if(strcmp(app.normalize,'Normalized'))
                ylim(app.UIAxes,[0 1]);
            else
                ylim(app.UIAxes,[-inf inf]);
            end
        end

        % Callback function
        function StartButtonValueChanged(app, event)

        end

        % Value changed function: WaterSlider
        function WaterSliderValueChanged(app, event)
            app.n_water = app.WaterSlider.Value;


            lambda = app.lambda;
            n_water = app.n_water;
            d_ti = app.Ti_slider;
            d_au = app.Au_slider;
            d_bio = app.d_bio;
            eps_bio = app.eps_bio;
            [RTM,theta_ref,theta_deg,epsilon] = SPRgui_func(lambda,d_au,d_ti,n_water,d_bio,eps_bio);

            if(strcmp(app.selected_theta,'Internal Angle'))
                x = theta_ref;
            elseif(strcmp(app.selected_theta,'External Angle'))
                x = theta_deg;
            end
            y = RTM;
            plot(app.UIAxes,x,y);
            if(strcmp(app.normalize,'Normalized'))
                ylim(app.UIAxes,[0 1]);
            else
                ylim(app.UIAxes,[-inf inf]);
            end
        end

        % Callback function
        function StartSliderValueChanged(app, event)
            %xlim(app.UIAxes,[app.theta_start,app.theta_stop])
        end

        % Value changed function: LambdaSlider
        function LambdaSliderValueChanged(app, event)
            app.lambda = app.LambdaSlider.Value*1e-9;

            lambda = app.lambda;
            n_water = app.n_water;
            d_ti = app.Ti_slider;
            d_au = app.Au_slider;
            d_bio = app.d_bio;
            eps_bio = app.eps_bio;
            [RTM,theta_ref,theta_deg,epsilon] = SPRgui_func(lambda,d_au,d_ti,n_water,d_bio,eps_bio);

            if(strcmp(app.selected_theta,'Internal Angle'))
                x = theta_ref;
            elseif(strcmp(app.selected_theta,'External Angle'))
                x = theta_deg;
            end
            y = RTM;
            plot(app.UIAxes,x,y);
            if(strcmp(app.normalize,'Normalized'))
                ylim(app.UIAxes,[0 1]);
            else
                ylim(app.UIAxes,[-inf inf]);
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            app.selected_theta = app.ButtonGroup.SelectedObject.Text;

            lambda = app.lambda;
            n_water = app.n_water;
            d_ti = app.Ti_slider;
            d_au = app.Au_slider;
            d_bio = app.d_bio;
            eps_bio = app.eps_bio;
            [RTM,theta_ref,theta_deg,epsilon] = SPRgui_func(lambda,d_au,d_ti,n_water,d_bio,eps_bio);

            if(strcmp(app.selected_theta,'Internal Angle'))
                x = theta_ref;
            elseif(strcmp(app.selected_theta,'External Angle'))
                x = theta_deg;
            end
            y = RTM;
            plot(app.UIAxes,x,y);
            if(strcmp(app.normalize,'Normalized'))
                ylim(app.UIAxes,[0 1]);
            else
                ylim(app.UIAxes,[-inf inf]);
            end
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            app.normalize = app.ButtonGroup_2.SelectedObject.Text;

            lambda = app.lambda;
            n_water = app.n_water;
            d_ti = app.Ti_slider;
            d_au = app.Au_slider;
            d_bio = app.d_bio;
            eps_bio = app.eps_bio;
            [RTM,theta_ref,theta_deg,epsilon] = SPRgui_func(lambda,d_au,d_ti,n_water,d_bio,eps_bio);

            if(strcmp(app.selected_theta,'Internal Angle'))
                x = theta_ref;
            elseif(strcmp(app.selected_theta,'External Angle'))
                x = theta_deg;
            end
            y = RTM;
            plot(app.UIAxes,x,y);
            if(strcmp(app.normalize,'Normalized'))
                ylim(app.UIAxes,[0 1]);
            else
                ylim(app.UIAxes,[-inf inf]);
            end

        end

        % Callback function
        function SystemLogSizeChanged(app, event)
            position = app.SystemLog.Position;

        end

        % Value changing function: BioSlider
        function BioSliderValueChanging(app, event)
            app.d_bio = event.Value*1e-9;

            lambda = app.lambda;
            n_water = app.n_water;
            d_ti = app.Ti_slider;
            d_au = app.Au_slider;
            d_bio = app.d_bio;
            eps_bio = app.eps_bio;
            [RTM,theta_ref,theta_deg,epsilon] = SPRgui_func(lambda,d_au,d_ti,n_water,d_bio,eps_bio);

            if(strcmp(app.selected_theta,'Internal Angle'))
                x = theta_ref;
            elseif(strcmp(app.selected_theta,'External Angle'))
                x = theta_deg;
            end
            y = RTM;
            plot(app.UIAxes,x,y);
            if(strcmp(app.normalize,'Normalized'))
                ylim(app.UIAxes,[0 1]);
            else
                ylim(app.UIAxes,[-inf inf]);
            end
        end

        % Value changing function: AuSlider_2
        function AuSlider_2ValueChanging(app, event)
            app.eps_bio = event.Value;

            lambda = app.lambda;
            n_water = app.n_water;
            d_ti = app.Ti_slider;
            d_au = app.Au_slider;
            d_bio = app.d_bio;
            eps_bio = app.eps_bio;
            [RTM,theta_ref,theta_deg,epsilon] = SPRgui_func(lambda,d_au,d_ti,n_water,d_bio,eps_bio);

            if(strcmp(app.selected_theta,'Internal Angle'))
                x = theta_ref;
            elseif(strcmp(app.selected_theta,'External Angle'))
                x = theta_deg;
            end
            y = RTM;
            plot(app.UIAxes,x,y);
            if(strcmp(app.normalize,'Normalized'))
                ylim(app.UIAxes,[0 1]);
            else
                ylim(app.UIAxes,[-inf inf]);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Surface Plasmon Resonance Simulation')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AuSliderLabel = uilabel(app.UIFigure);
            app.AuSliderLabel.Text = 'Au';
            app.AuSlider = uislider(app.UIFigure);
            app.AuSlider.ValueChangingFcn = createCallbackFcn(app, @AuSliderValueChanging, true);
            app.AuSlider.Value = 50;
            app.TiSliderLabel = uilabel(app.UIFigure);
            app.TiSliderLabel.Text = 'Ti';
            app.TiSlider = uislider(app.UIFigure);
            app.TiSlider.Limits = [0 20];
            app.TiSlider.ValueChangingFcn = createCallbackFcn(app, @TiSliderValueChanging, true);
            app.TiSlider.Value = 1;
            app.WaterSliderLabel = uilabel(app.UIFigure);
            app.WaterSliderLabel.Text = 'Water';
            app.WaterSlider = uislider(app.UIFigure);
            app.WaterSlider.Limits = [1 2];
            app.WaterSlider.ValueChangedFcn = createCallbackFcn(app, @WaterSliderValueChanged, true);
            app.WaterSlider.Value = 1.33;
            app.LambdaSliderLabel = uilabel(app.UIFigure);
            app.LambdaSliderLabel.Text = 'Lambda';
            app.LambdaSlider = uislider(app.UIFigure);
            app.LambdaSlider.Limits = [400 1500];
            app.LambdaSlider.ValueChangedFcn = createCallbackFcn(app, @LambdaSliderValueChanged, true);
            app.LambdaSlider.Value = 633;
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.InternalAngleButton = uiradiobutton(app.ButtonGroup);
            app.InternalAngleButton.Text = 'Internal Angle';
            app.InternalAngleButton.Value = true;
            app.ExternalAngleButton = uiradiobutton(app.ButtonGroup);
            app.ExternalAngleButton.Text = 'External Angle';
            app.ButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.NormalizedButton = uiradiobutton(app.ButtonGroup_2);
            app.NormalizedButton.Text = 'Normalized';
            app.NormalizedButton.Value = true;
            app.AutoScaleButton = uiradiobutton(app.ButtonGroup_2);
            app.AutoScaleButton.Text = 'Auto Scale';
            app.nmLabel = uilabel(app.UIFigure);
            app.nmLabel.Text = 'nm';
            app.nmLabel_2 = uilabel(app.UIFigure);
            app.nmLabel_2.Text = 'nm';
            app.RIULabel = uilabel(app.UIFigure);
            app.RIULabel.Text = 'RIU';
            app.nmLabel_3 = uilabel(app.UIFigure);
            app.nmLabel_3.Text = 'nm';
            app.AuSlider_2 = uislider(app.UIFigure);
            app.AuSlider_2.Limits = [1 3];
            app.AuSlider_2.ValueChangingFcn = createCallbackFcn(app, @AuSlider_2ValueChanging, true);
            app.AuSlider_2.Value = 3;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.BioSliderLabel = uilabel(app.UIFigure);
            app.BioSliderLabel.Text = 'Bio';
            app.BioSlider = uislider(app.UIFigure);
            app.BioSlider.Limits = [0 20];
            app.BioSlider.ValueChangingFcn = createCallbackFcn(app, @BioSliderValueChanging, true);
            app.BioSlider.Value = 1;
            app.BioSliderLabel_2 = uilabel(app.UIFigure);
            app.BioSliderLabel_2.Text = 'epsBio';
            app.nmLabel_5 = uilabel(app.UIFigure);
            app.nmLabel_5.Text = 'nm';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.UIAxes.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SPRsim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end